classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Panel           matlab.ui.container.Panel
        GraficarButton  matlab.ui.control.Button
        DistanciaentreplacasSliderLabel  matlab.ui.control.Label
        sl_distance     matlab.ui.control.Slider
        axes            matlab.ui.control.UIAxes
    end

 
    properties (Access = public)
        amplitud = 1
    end
    
    methods (Access = public)
    end
    
    methods (Access = private)
        
        function update_plot(app)
            
            %Entregable 1 en 3D 4 cargas

            lz = 0.1; %distancia del centro a la orilla por eje z
            lx = 0.005;  %ancho de la placa
            ly = 0.1;  %distancia del centro a la orilla por eje y
            movX = app.sl_distance.Value./2; % mitad de la distancia entre placas
            
            %VERTICES Y CARAS DE LÁMINA (+)
                       % x       y     z
            vertices = [-lx-movX -ly  -lz; %v1
                        -lx-movX   ly  -lz; %v2
                         -movX   ly  -lz; %v3
                         -movX  -ly  -lz; %v4
                        -lx-movX  -ly   lz; %v5
                        -lx-movX   ly   lz; %v6
                         -movX   ly   lz; %v7
                         -movX  -ly   lz]; %v8
                     
            caras = [1 5 8 4; %c1
                     1 5 6 2; %c2
                     2 3 7 6; %c3
                     3 4 8 7; %c4
                     5 6 7 8; %c5
                     1 2 3 4]; %c6
            
                 
            %VERTICES Y CARAS DE LÁMINA (-)
                       % x       y     z
            vertices2 = [+movX -ly  -lz; %v1
                        +movX   ly  -lz; %v2
                         lx+movX   ly  -lz; %v3
                         lx+movX  -ly  -lz; %v4
                        +movX  -ly   lz; %v5
                        +movX   ly   lz; %v6
                         lx+movX   ly   lz; %v7
                         lx+movX  -ly   lz]; %v8
            
            xl = linspace(-2*movX,2*movX,11);  % Valores de x que vamos a evaluar
            yl = linspace(-1.5*ly,1.5*ly,11);  % Valores de y que vamos a evaluar
            zl = linspace(-1.5*lz,1.5*lz,11); % Valores de z que vamos a evaluar
            
            [xGrid, yGrid, zGrid]= meshgrid(xl,yl,zl);
            
            %PROPIEDADES DEL CAMPO ELÉCTRICO
            Q = 1e-6; %Carga (+)
            q = Q./2;
            eps0 = 8.854e-12; %Constante dieléctrica
            A = 4*(ly * lz); %Área de la lámina positiva
            
            %PROPIEDADES PARA LOS VECTORES
            Px = xGrid;
            Py = yGrid;
            Pz = zGrid;
            
            % Campo Q1 (Placa positiva) 
            rx = xGrid -(-movX);
            ry = yGrid - 0;
            rz = zGrid - lz;
            r = sqrt(rx.^2 + ry.^2 + rz.^2); % distancia de la particula (magnitud)
            Ex1 = ((q / 4*pi*eps0) * rx) ./ r.^3; % E = [(k*q) / sqrt ( (x-qx)^2 + (y-qy)^2 + (z-qz)^2) )^2 ] * r unitario
            Ey1 = ((q / 4*pi*eps0) * ry) ./ r.^3;
            Ez1 = ((q / 4*pi*eps0) * rz) ./ r.^3;
            
            % Campo Q2 (placa positiva)
            rx = xGrid -(movX);
            ry = yGrid - 0;
            rz = zGrid - (-lz);
            r = sqrt(rx.^2 + ry.^2 + rz.^2); % distancia de la particula (magnitud)
            Ex2 = ((q / 4*pi*eps0) * rx) ./ r.^3; % E = [(k*q) / sqrt ( (x-qx)^2 + (y-qy)^2 + (z-qz)^2) )^2 ] * r unitario
            Ey2 = ((q / 4*pi*eps0) * ry) ./ r.^3;
            Ez2 = ((q / 4*pi*eps0) * rz) ./ r.^3;
            
            % Campo Q3 (Placa negativa)
            rx = xGrid -(movX);
            ry = yGrid - 0;
            rz = zGrid -lz;
            r = sqrt(rx.^2 + ry.^2 + rz.^2); % distancia de la particula (magnitud)
            Ex3 = ((-q / 4*pi*eps0) * rx) ./ r.^3; % E = [(k*q) / sqrt ( (x-qx)^2 + (y-qy)^2 + (z-qz)^2) )^2 ] * r unitario
            Ey3 = ((-q / 4*pi*eps0) * ry) ./ r.^3;
            Ez3 = ((-q / 4*pi*eps0) * rz) ./ r.^3;
            
            % Campo Q4 (Placa negativa)
            rx = xGrid -(movX);
            ry = yGrid - 0;
            rz = zGrid - (-lz);
            r = sqrt(rx.^2 + ry.^2 + rz.^2); % distancia de la particula (magnitud)
            Ex4 = ((-q / 4*pi*eps0) * rx) ./ r.^3; % E = [(k*q) / sqrt ( (x-qx)^2 + (y-qy)^2 + (z-qz)^2) )^2 ] * r unitario
            Ey4 = ((-q / 4*pi*eps0) * ry) ./ r.^3;
            Ez4 = ((-q / 4*pi*eps0) * rz) ./ r.^3;
            
            
            %COMPONENTES DE LOS VECTORES
            Ex = Ex1 + Ex2 +Ex3 + Ex4;
            Ey = Ey1 + Ey2 +Ey3 + Ey4;
            Ez = Ez1 + Ez2 +Ez3 + Ez4;
            
            E = sqrt(Ex.^2 + Ey .^2 + Ez.^2);
            
            cla(app.axes, "reset")
            
            hold(app.axes, "on")
            
            patch(app.axes,'Faces',caras,'Vertices',vertices,'FaceColor','r','facealpha',0.5);
            patch(app.axes,'Faces',caras,'Vertices',vertices2,'FaceColor','b','facealpha',0.5);
            scatter3(app.axes, -movX,0,lz,'g','linewidth',5)
            scatter3(app.axes,-movX,0,-lz,'g','linewidth',5);
            scatter3(app.axes,movX,0,lz,'g','linewidth',5)
            scatter3(app.axes,movX,0,-lz,'g','linewidth',5);
            
            axis(app.axes, "equal")
            xlabel(app.axes,'x')
            ylabel(app.axes,'y')
            zlabel(app.axes,'z')
            
            quiver3(app.axes,xGrid, yGrid, zGrid,Ex./E,Ey./E,Ez./E);
            
            hold(app.axes, "off")
            
            axis(app.axes, [-2*movX 2*movX -1.5*ly 1.5*ly -1.5*lz 1.5*lz]);
            view(app.axes, 30,30); %Establecer la vista de el espacio 3D
            grid(app.axes, "on");

            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraficarButton
        function plotButtonPushed(app, event)
            update_plot(app)
        end

        % Value changing function: sl_distance
        function sl_distanceValueChanging(app, event)
 
            update_plot(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.GraficarButton = uibutton(app.Panel, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.DistanciaentreplacasSliderLabel = uilabel(app.Panel);
            app.DistanciaentreplacasSliderLabel.Text = 'Distancia entre placas';
            app.sl_distance = uislider(app.Panel);
            app.sl_distance.Limits = [0.01 0.5];
            app.sl_distance.ValueChangingFcn = createCallbackFcn(app, @sl_distanceValueChanging, true);
            app.sl_distance.Value = 0.05;
            app.axes = uiaxes(app.UIFigure);
            title(app.axes, 'Gráfica')
            xlabel(app.axes, 'X')
            ylabel(app.axes, 'Y')
            zlabel(app.axes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end